classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_1       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_s       matlab.ui.control.Button
        label          matlab.ui.control.Label
        CheckBox_1     matlab.ui.control.CheckBox
        texture_label  matlab.ui.control.Label
        CheckBox_2     matlab.ui.control.CheckBox
        error_label    matlab.ui.control.Label
    end


    properties (Access = public)
        texture = 0
        submitted = 0;
        trial = 1;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.texture = 1;
            app.Button_1.BackgroundColor = [0,1,0];
            app.Button_2.BackgroundColor = [.96,.96,.96];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.texture = 2;
            app.Button_1.BackgroundColor = [.96,.96,.96]; 
            app.Button_2.BackgroundColor = [0,1,0];
        end

        % Button pushed function: Button_s
        function Button_sPushed(app, event)
            if (app.CheckBox_2.Value == 1 || app.CheckBox_1.Value == 1)
                app.submitted = app.CheckBox_1.Value + app.CheckBox_2.Value*2;
                app.trial = app.trial + 1;
                app.Button_1.BackgroundColor = [0, 1, 0];
                app.Button_1.BackgroundColor = [.96,.96,.96];
                app.Button_2.BackgroundColor = [.96,.96,.96];
                app.CheckBox_1.Value = 0;
                app.CheckBox_2.Value = 0;     
                app.error_label.Text = ''; 
            else
                app.error_label.Text = 'Please make a selection';
            end
            app.Button_1.BackgroundColor = [.96,.96,.96];
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            if app.CheckBox_1.Value == 1
                app.CheckBox_2.Value = 0;
            end            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if app.CheckBox_2.Value == 1
                app.CheckBox_1.Value = 0;
            end     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_s = uibutton(app.UIFigure, 'push');
            app.Button_s.ButtonPushedFcn = createCallbackFcn(app, @Button_sPushed, true);
            app.Button_s.Text = 'Submit';
            app.label = uilabel(app.UIFigure);
            app.label.Text = 'Which texture feels stickier?';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.texture_label = uilabel(app.UIFigure);
            app.texture_label.Text = 'Select a texture to display';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '2';
            app.error_label = uilabel(app.UIFigure);
            app.error_label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end