classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        command    matlab.ui.control.Label
        b1         matlab.ui.control.Button
        b2         matlab.ui.control.Button
        b3         matlab.ui.control.Button
        b4         matlab.ui.control.Button
        b5         matlab.ui.control.Button
        b6         matlab.ui.control.Button
        b7         matlab.ui.control.Button
        b8         matlab.ui.control.Button
        b9         matlab.ui.control.Button
        b0         matlab.ui.control.Button
        clear      matlab.ui.control.Button
        sub        matlab.ui.control.Button
        EditField  matlab.ui.control.NumericEditField
        point      matlab.ui.control.Button
    end


    properties (Access = public)
        total = 0;
        flag = 1;
        m = 10;
        finalvalue = -1;
        trial = 1;
    end


    methods (Access = private)

        % Button pushed function: b0
        function b0ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10;
            else
                app.total = app.total + 0/10;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 1;
            else
                app.total = app.total + 1/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b2
        function b2ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 2;
            else
                app.total = app.total + 2/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b3
        function b3ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 3;
            else
                app.total = app.total + 3/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b4
        function b4ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 4;
            else
                app.total = app.total + 4/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b5
        function b5ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 5;
            else
                app.total = app.total + 5/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b6
        function b6ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 6;
            else
                app.total = app.total + 6/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b7
        function b7ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 7;
            else
                app.total = app.total + 7/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b8
        function b8ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 8;
            else
                app.total = app.total + 8/app.m;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: b9
        function b9ButtonPushed(app, event)
            if app.flag
                app.total = app.total*10 + 9;
            else
                app.total = app.total + 9/app.m;;
                app.m = app.m*10;
            end
            app.EditField.Value = app.total;
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.total = 0;
            app.EditField.Value = app.total;
            app.m = 10;
            app.flag = 1;
        end

        % Button pushed function: point
        function pointButtonPushed(app, event)
            app.flag = 0;
        end

        % Button pushed function: sub
        function subButtonPushed(app, event)
            app.finalvalue = app.total;
            app.total = 0;  
            app.flag = 1;
            app.m = 10;
            app.EditField.Value = app.total;
            app.trial = app.trial + 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.command = uilabel(app.UIFigure);
            app.command.Text = 'Rate how sticky the texture feels';
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = '1';
            app.b2 = uibutton(app.UIFigure, 'push');
            app.b2.ButtonPushedFcn = createCallbackFcn(app, @b2ButtonPushed, true);
            app.b2.Text = '2';
            app.b3 = uibutton(app.UIFigure, 'push');
            app.b3.ButtonPushedFcn = createCallbackFcn(app, @b3ButtonPushed, true);
            app.b3.Text = '3';
            app.b4 = uibutton(app.UIFigure, 'push');
            app.b4.ButtonPushedFcn = createCallbackFcn(app, @b4ButtonPushed, true);
            app.b4.Text = '4';
            app.b5 = uibutton(app.UIFigure, 'push');
            app.b5.ButtonPushedFcn = createCallbackFcn(app, @b5ButtonPushed, true);
            app.b5.Text = '5';
            app.b6 = uibutton(app.UIFigure, 'push');
            app.b6.ButtonPushedFcn = createCallbackFcn(app, @b6ButtonPushed, true);
            app.b6.Text = '6';
            app.b7 = uibutton(app.UIFigure, 'push');
            app.b7.ButtonPushedFcn = createCallbackFcn(app, @b7ButtonPushed, true);
            app.b7.Text = '7';
            app.b8 = uibutton(app.UIFigure, 'push');
            app.b8.ButtonPushedFcn = createCallbackFcn(app, @b8ButtonPushed, true);
            app.b8.Text = '8';
            app.b9 = uibutton(app.UIFigure, 'push');
            app.b9.ButtonPushedFcn = createCallbackFcn(app, @b9ButtonPushed, true);
            app.b9.Text = '9';
            app.b0 = uibutton(app.UIFigure, 'push');
            app.b0.ButtonPushedFcn = createCallbackFcn(app, @b0ButtonPushed, true);
            app.b0.Text = '0';
            app.clear = uibutton(app.UIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = {'clear'; ''};
            app.sub = uibutton(app.UIFigure, 'push');
            app.sub.ButtonPushedFcn = createCallbackFcn(app, @subButtonPushed, true);
            app.sub.Text = 'submit';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%11.10g';
            app.point = uibutton(app.UIFigure, 'push');
            app.point.ButtonPushedFcn = createCallbackFcn(app, @pointButtonPushed, true);
            app.point.Text = '.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end