classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StickinessknobLabel  matlab.ui.control.Label
        leaststickyLabel     matlab.ui.control.Label
        moststickyLabel      matlab.ui.control.Label
        KnobLabel            matlab.ui.control.Label
        Knob                 matlab.ui.control.Knob
        EditField            matlab.ui.control.NumericEditField
        FinishedButton       matlab.ui.control.Button
        command              matlab.ui.control.Label
    end

    properties (Access = public)
        Property3 % Description
        knob_value = .5;
        flag = 0;
        finished = 0;
    end


    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.knob_value = app.Knob.Value;
            app.EditField.Value = app.Knob.Value;
            app.flag = 1;
            app.command.Text = 'Remove the finger';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Knob.Value = app.EditField.Value;
            app.flag = 1;
            app.command.Text = 'Remove the finger';
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            app.finished = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StickinessknobLabel = uilabel(app.UIFigure);
            app.StickinessknobLabel.Text = 'Stickiness knob';
            app.leaststickyLabel = uilabel(app.UIFigure);
            app.leaststickyLabel.Text = 'least sticky';
            app.moststickyLabel = uilabel(app.UIFigure);
            app.moststickyLabel.Text = 'most sticky';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = '';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 1];
            app.Knob.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 0.5;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.5;
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.command = uilabel(app.UIFigure);
            app.command.Text = 'Start swiping!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end