classdef parameter_explore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        IstuckmAKnobLabel  matlab.ui.control.Label
        IstuckmAKnob       matlab.ui.control.Knob
        IslipmAKnobLabel   matlab.ui.control.Label
        IslipmAKnob        matlab.ui.control.Knob
        IslidemAKnobLabel  matlab.ui.control.Label
        IslidemAKnob       matlab.ui.control.Knob
        sigmaKnobLabel     matlab.ui.control.Label
        sigmaKnob          matlab.ui.control.Knob
        deltaKnobLabel     matlab.ui.control.Label
        deltaKnob          matlab.ui.control.Knob
        zerosensorsButton  matlab.ui.control.Button
        IstuckmAEditField  matlab.ui.control.NumericEditField
        IslipmAEditField   matlab.ui.control.NumericEditField
        IslidemAEditField  matlab.ui.control.NumericEditField
        sigmaEditField     matlab.ui.control.NumericEditField
        deltaEditField     matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Istuckval = 0; % Description
        Islipval = 0;
        Islideval = 0;
        sigma = 0;
        delta = 0;
        updated = 0;
    end


    methods (Access = private)

        % Value changed function: IslidemAEditField
        function IslidemAEditFieldValueChanged(app, event)
            app.IslidemAKnob.Value = app.IslidemAEditField.Value;  
            app.updated = 1;
        end

        % Value changed function: IslidemAKnob
        function IslidemAKnobValueChanged(app, event)
            app.IslidemAEditField.Value = app.IslidemAKnob.Value;
            app.Islideval = app.IslidemAKnob.Value;
            app.updated = 1;
        end

        % Value changed function: IslipmAEditField
        function IslipmAEditFieldValueChanged(app, event)
            app.IslipmAKnob.Value = app.IslipmAEditField.Value; 
            app.updated = 1;
        end

        % Value changed function: IslipmAKnob
        function IslipmAKnobValueChanged(app, event)
            app.IslipmAEditField.Value = app.IslipmAKnob.Value;
            app.Islipval = app.IslipmAKnob.Value;
            app.updated = 1;
        end

        % Value changed function: IstuckmAEditField
        function IstuckmAEditFieldValueChanged(app, event)
            app.IstuckmAKnob.Value = app.IstuckmAEditField.Value;   
            app.updated = 1;
        end

        % Value changed function: IstuckmAKnob
        function IstuckmAKnobValueChanged(app, event)
            app.IstuckmAEditField.Value = app.IstuckmAKnob.Value;
            app.Istuckval = app.IstuckmAKnob.Value;
            app.updated = 1;
        end

        % Value changed function: deltaEditField
        function deltaEditFieldValueChanged(app, event)
            app.deltaKnob.Value = app.deltaEditField.Value;
            app.updated = 1;
        end

        % Value changed function: deltaKnob
        function deltaKnobValueChanged(app, event)
            app.deltaEditField.Value = app.deltaKnob.Value;              
            app.delta = app.deltaKnob.Value;
            app.updated = 1;
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            app.sigmaKnob.Value = app.sigmaEditField.Value;  
            app.updated = 1;
        end

        % Value changed function: sigmaKnob
        function sigmaKnobValueChanged(app, event)
            app.sigmaEditField.Value = app.sigmaKnob.Value;  
            app.sigma = app.sigmaKnob.Value;
            app.updated = 1;
        end

        % Button pushed function: zerosensorsButton
        function zerosensorsButtonPushed(app, event)
            app.updated = 2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.IstuckmAKnobLabel = uilabel(app.UIFigure);
            app.IstuckmAKnobLabel.Text = 'I stuck (mA)';
            app.IstuckmAKnob = uiknob(app.UIFigure, 'continuous');
            app.IstuckmAKnob.Limits = [0 7];
            app.IstuckmAKnob.MajorTicks = [0 1 2 3 4 5 6 7];
            app.IstuckmAKnob.ValueChangedFcn = createCallbackFcn(app, @IstuckmAKnobValueChanged, true);
            app.IslipmAKnobLabel = uilabel(app.UIFigure);
            app.IslipmAKnobLabel.Text = 'I slip (mA)';
            app.IslipmAKnob = uiknob(app.UIFigure, 'continuous');
            app.IslipmAKnob.Limits = [0 7];
            app.IslipmAKnob.MajorTicks = [0 1 2 3 4 5 6 7];
            app.IslipmAKnob.ValueChangedFcn = createCallbackFcn(app, @IslipmAKnobValueChanged, true);
            app.IslidemAKnobLabel = uilabel(app.UIFigure);
            app.IslidemAKnobLabel.Text = 'I slide (mA)';
            app.IslidemAKnob = uiknob(app.UIFigure, 'continuous');
            app.IslidemAKnob.Limits = [0 7];
            app.IslidemAKnob.MajorTicks = [0 1 2 3 4 5 6 7];
            app.IslidemAKnob.ValueChangedFcn = createCallbackFcn(app, @IslidemAKnobValueChanged, true);
            app.sigmaKnobLabel = uilabel(app.UIFigure);
            app.sigmaKnobLabel.Text = 'sigma';
            app.sigmaKnob = uiknob(app.UIFigure, 'continuous');
            app.sigmaKnob.Limits = [0 1];
            app.sigmaKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.sigmaKnob.ValueChangedFcn = createCallbackFcn(app, @sigmaKnobValueChanged, true);
            app.deltaKnobLabel = uilabel(app.UIFigure);
            app.deltaKnobLabel.Text = 'delta';
            app.deltaKnob = uiknob(app.UIFigure, 'continuous');
            app.deltaKnob.Limits = [0 1];
            app.deltaKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.deltaKnob.ValueChangedFcn = createCallbackFcn(app, @deltaKnobValueChanged, true);
            app.zerosensorsButton = uibutton(app.UIFigure, 'push');
            app.zerosensorsButton.ButtonPushedFcn = createCallbackFcn(app, @zerosensorsButtonPushed, true);
            app.zerosensorsButton.Text = 'zero sensors';
            app.IstuckmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.IstuckmAEditField.ValueChangedFcn = createCallbackFcn(app, @IstuckmAEditFieldValueChanged, true);
            app.IslipmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.IslipmAEditField.ValueChangedFcn = createCallbackFcn(app, @IslipmAEditFieldValueChanged, true);
            app.IslidemAEditField = uieditfield(app.UIFigure, 'numeric');
            app.IslidemAEditField.ValueChangedFcn = createCallbackFcn(app, @IslidemAEditFieldValueChanged, true);
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.deltaEditField = uieditfield(app.UIFigure, 'numeric');
            app.deltaEditField.ValueChangedFcn = createCallbackFcn(app, @deltaEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = parameter_explore

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end